classdef lasso_gpt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WideAreaCaptureButton         matlab.ui.control.Button
        ConstantAreaCaptureButton     matlab.ui.control.Button
        RAANOffsetEditField           matlab.ui.control.NumericEditField
        RAANOffsetEditFieldLabel      matlab.ui.control.Label
        ViewingAngleEditField         matlab.ui.control.NumericEditField
        ViewingAngleEditFieldLabel    matlab.ui.control.Label
        NumberofPlanesEditField       matlab.ui.control.NumericEditField
        NumberofPlanesEditFieldLabel  matlab.ui.control.Label
        AoILongitudeEditField         matlab.ui.control.NumericEditField
        AoILongitudeEditFieldLabel    matlab.ui.control.Label
        AoILatitudeEditField          matlab.ui.control.NumericEditField
        AoILatitudeEditFieldLabel     matlab.ui.control.Label
        CoverageMapButton             matlab.ui.control.Button
        LASSOLabel                    matlab.ui.control.Label
        OrbitPropagatorDropDown       matlab.ui.control.DropDown
        OrbitPropagatorDropDownLabel  matlab.ui.control.Label
        SecondsEditField              matlab.ui.control.NumericEditField
        SecondsEditFieldLabel         matlab.ui.control.Label
        MinuteEditField               matlab.ui.control.NumericEditField
        MinuteEditFieldLabel          matlab.ui.control.Label
        HourEditField                 matlab.ui.control.NumericEditField
        HourEditFieldLabel            matlab.ui.control.Label
        DayEditField                  matlab.ui.control.NumericEditField
        DayEditFieldLabel             matlab.ui.control.Label
        MonthEditField                matlab.ui.control.NumericEditField
        MonthEditFieldLabel           matlab.ui.control.Label
        YearEditField                 matlab.ui.control.NumericEditField
        YearEditFieldLabel            matlab.ui.control.Label
        SteptimeEditField             matlab.ui.control.NumericEditField
        SteptimeEditFieldLabel        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        EccentricityEditField         matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel    matlab.ui.control.Label
        AskButton                     matlab.ui.control.Button
        AnswerTextArea                matlab.ui.control.TextArea
        AnswerTextAreaLabel           matlab.ui.control.Label
        QuestionTextArea              matlab.ui.control.TextArea
        QuestionTextAreaLabel         matlab.ui.control.Label
        InclinationEditField          matlab.ui.control.NumericEditField
        InclinationEditFieldLabel     matlab.ui.control.Label
        AltitudeEditField             matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel        matlab.ui.control.Label
        NumberofSatellitesEditField   matlab.ui.control.NumericEditField
        NumberofSatellitesEditFieldLabel  matlab.ui.control.Label
        RunVisualizationButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        scenario % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunVisualizationButton
        function RunVisualizationButtonPushed(app, event)
            % Retrieve inputs from the app components
            Year = app.YearEditField.Value;
            Month = app.MonthEditField.Value;
            Day = app.DayEditField.Value;
            Hour = app.HourEditField.Value;
            Minute = app.MinuteEditField.Value;
            Seconds = app.SecondsEditField.Value;
            durationHours = app.DurationEditField.Value;
            steptimeSeconds = app.SteptimeEditField.Value;
            Altitude = app.AltitudeEditField.Value;
            Eccentricity = app.EccentricityEditField.Value;
            Inclination = app.InclinationEditField.Value;
            number_of_SATs = app.NumberofSatellitesEditField.Value;
            number_of_planes = app.NumberofPlanesEditField.Value;
            Orbit_Propagator = app.OrbitPropagatorDropDown.Value;
            RAAN_offset = app.RAANOffsetEditField.Value;
            
            % Call the function to run the scenario
            runSatelliteScenario(Year, Month, Day, Hour, Minute, Seconds, ...
                durationHours, steptimeSeconds, Altitude, Eccentricity, Inclination, ...
                number_of_SATs, number_of_planes, Orbit_Propagator, RAAN_offset);
        end

        % Button pushed function: AskButton
        function AskButtonPushed(app, event)
            % Get the user question from the QuestionTextArea
            userQuestion = app.QuestionTextArea.Value;
            
            % Prepare the Python command with the user's question
            pythonCommand = sprintf('/Users/andrewyoung/bin/python3 /Users/andrewyoung/Desktop/LASSO/assistant_interaction.py "%s"', userQuestion{1});
            
            % Run the Python script and capture the output (assistant's response)
            [status, response] = system(pythonCommand);
            
            % Check if the command ran successfully
            if status == 0
                % Initialize variables
                assistantResponse = '';
                
                % Split the response into individual JSON objects and texts
                jsonObjects = regexp(response, '({[^}]*})', 'match');
                
                % Loop through each JSON object
                for i = 1:length(jsonObjects)
                    jsonString = jsonObjects{i};
                    try
                        % Parse the JSON string
                        jsonData = jsondecode(jsonString);
                        
                        % Check if this JSON object is a message from assistant
                        if isfield(jsonData, 'role') && strcmp(jsonData.role, 'assistant')
                            % Extract the assistant's response
                            content = jsonData.content;
                            
                            % Content could be a cell array or struct array
                            if iscell(content) || isstruct(content)
                                % Assuming content is a cell array of structs
                                for j = 1:length(content)
                                    if isfield(content{j}, 'text') && isfield(content{j}.text, 'value')
                                        assistantResponse = [assistantResponse, content{j}.text.value, '\n'];
                                    elseif isfield(content(j), 'text') && isfield(content(j).text, 'value')
                                        assistantResponse = [assistantResponse, content(j).text.value, '\n'];
                                    end
                                end
                            end
                        end
                    catch
                        % If jsondecode fails, skip this object
                        continue
                    end
                end
                
                % If assistantResponse is still empty, try to capture any text after the JSON data
                if isempty(strtrim(assistantResponse))
                    % Find the index of the last closing brace '}'
                    index = find(response == '}', 1, 'last');
                    % Extract everything after the last closing brace
                    assistantResponse = strtrim(response(index+1:end));
                end
                
                % Post-process to remove asterisks, hashtags, and citation links
                % Remove asterisks (*) and other markdown-style symbols
                assistantResponse = regexprep(assistantResponse, '[\*\#]', '');
                
                % Optionally remove any in-text citations if they follow a specific pattern
                assistantResponse = regexprep(assistantResponse, '\[[^\]]*\]', '');  % Removes [anything in brackets]
                assistantResponse = regexprep(assistantResponse, '【\d+:\d+†source】', ''); % Removes   format links
                
                % Create formatted text to append to AnswerTextArea with both the user question and assistant response
                newEntry = sprintf("User: %s\n\nLASSO_GPT: %s\n\n", userQuestion{1}, strtrim(assistantResponse));
                
                % Append the new entry to the existing content in AnswerTextArea
                if isempty(app.AnswerTextArea.Value)
                    app.AnswerTextArea.Value = newEntry;  % If empty, just set the new entry
                else
                    app.AnswerTextArea.Value = [app.AnswerTextArea.Value; ""; newEntry];  % Append with a newline for separation
                end
                
                % Clear the QuestionTextArea for new input
                app.QuestionTextArea.Value = "";
                
            else
                % If an error occurs, display an error message in AnswerTextArea
                app.AnswerTextArea.Value = 'Error: Could not get a response from the Assistant.';
            end
        end

        % Button pushed function: CoverageMapButton
        function CoverageMapButtonPushed(app, event)
           % Retrieve inputs from the app components
            Year = app.YearEditField.Value;
            Month = app.MonthEditField.Value;
            Day = app.DayEditField.Value;
            Hour = app.HourEditField.Value;
            Minute = app.MinuteEditField.Value;
            Seconds = app.SecondsEditField.Value;
            durationHours = app.DurationEditField.Value;
            steptimeSeconds = app.SteptimeEditField.Value;
            Altitude = app.AltitudeEditField.Value;
            Eccentricity = app.EccentricityEditField.Value;
            Inclination = app.InclinationEditField.Value;
            number_of_SATs = app.NumberofSatellitesEditField.Value;
            number_of_planes = app.NumberofPlanesEditField.Value;
            Orbit_Propagator = app.OrbitPropagatorDropDown.Value;
            viewing_angle = app.ViewingAngleEditField.Value;

            % Call the function to run the scenario
            coverage_map(Year, Month, Day, Hour, Minute, Seconds, ...
                durationHours, steptimeSeconds, Altitude, Eccentricity, Inclination, ...
                number_of_SATs, number_of_planes, Orbit_Propagator, viewing_angle);
        end

        % Button pushed function: ConstantAreaCaptureButton
        function ConstantAreaCaptureButtonPushed(app, event)
            initializeConstellation(app);
            % Run the Constant Area Capture function
            constantAreaCapture(app);
        end

        % Button pushed function: WideAreaCaptureButton
        function WideAreaCaptureButtonPushed(app, event)
            initializeConstellation(app);
            wideAreaCapture(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunVisualizationButton = uibutton(app.UIFigure, 'push');
            app.RunVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunVisualizationButtonPushed, true);
            app.RunVisualizationButton.Text = 'Run Visualization';
            app.NumberofSatellitesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSatellitesEditFieldLabel.Text = 'Number of Satellites';
            app.NumberofSatellitesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofSatellitesEditField.Value = 15;
            app.AltitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudeEditField.Value = 500;
            app.InclinationEditFieldLabel = uilabel(app.UIFigure);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.UIFigure, 'numeric');
            app.InclinationEditField.Value = 45;
            app.QuestionTextAreaLabel = uilabel(app.UIFigure);
            app.QuestionTextAreaLabel.Text = 'Question';
            app.QuestionTextArea = uitextarea(app.UIFigure);
            app.AnswerTextAreaLabel = uilabel(app.UIFigure);
            app.AnswerTextAreaLabel.Text = 'Answer';
            app.AnswerTextArea = uitextarea(app.UIFigure);
            app.AskButton = uibutton(app.UIFigure, 'push');
            app.AskButton.ButtonPushedFcn = createCallbackFcn(app, @AskButtonPushed, true);
            app.AskButton.Text = 'Ask';
            app.EccentricityEditFieldLabel = uilabel(app.UIFigure);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.UIFigure, 'numeric');
            app.EccentricityEditField.Value = 1e-05;
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 0.25;
            app.SteptimeEditFieldLabel = uilabel(app.UIFigure);
            app.SteptimeEditFieldLabel.Text = 'Steptime';
            app.SteptimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SteptimeEditField.Value = 15;
            app.YearEditFieldLabel = uilabel(app.UIFigure);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearEditField.Value = 2024;
            app.MonthEditFieldLabel = uilabel(app.UIFigure);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonthEditField.Value = 3;
            app.DayEditFieldLabel = uilabel(app.UIFigure);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayEditField.Value = 21;
            app.HourEditFieldLabel = uilabel(app.UIFigure);
            app.HourEditFieldLabel.Text = 'Hour';
            app.HourEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinuteEditFieldLabel = uilabel(app.UIFigure);
            app.MinuteEditFieldLabel.Text = 'Minute';
            app.MinuteEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsEditFieldLabel = uilabel(app.UIFigure);
            app.SecondsEditFieldLabel.Text = 'Seconds';
            app.SecondsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrbitPropagatorDropDownLabel = uilabel(app.UIFigure);
            app.OrbitPropagatorDropDownLabel.Text = 'Orbit Propagator';
            app.OrbitPropagatorDropDown = uidropdown(app.UIFigure);
            app.OrbitPropagatorDropDown.Items = {'SGP4', 'Option 2', 'Option 3', 'Option 4'};
            app.OrbitPropagatorDropDown.Value = 'SGP4';
            app.LASSOLabel = uilabel(app.UIFigure);
            app.LASSOLabel.Text = 'LASSO';
            app.CoverageMapButton = uibutton(app.UIFigure, 'push');
            app.CoverageMapButton.ButtonPushedFcn = createCallbackFcn(app, @CoverageMapButtonPushed, true);
            app.CoverageMapButton.Text = 'Coverage Map';
            app.AoILatitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AoILatitudeEditFieldLabel.Text = 'AoILatitude';
            app.AoILatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AoILatitudeEditField.Value = 39.7596;
            app.AoILongitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AoILongitudeEditFieldLabel.Text = 'AoILongitude';
            app.AoILongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AoILongitudeEditField.Value = -121.6219;
            app.NumberofPlanesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPlanesEditFieldLabel.Text = 'Number of Planes';
            app.NumberofPlanesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPlanesEditField.Value = 4;
            app.ViewingAngleEditFieldLabel = uilabel(app.UIFigure);
            app.ViewingAngleEditFieldLabel.Text = 'Viewing Angle';
            app.ViewingAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ViewingAngleEditField.Value = 45;
            app.RAANOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.RAANOffsetEditFieldLabel.Text = 'RAAN Offset';
            app.RAANOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConstantAreaCaptureButton = uibutton(app.UIFigure, 'push');
            app.ConstantAreaCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantAreaCaptureButtonPushed, true);
            app.ConstantAreaCaptureButton.Text = 'Constant Area Capture';
            app.WideAreaCaptureButton = uibutton(app.UIFigure, 'push');
            app.WideAreaCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @WideAreaCaptureButtonPushed, true);
            app.WideAreaCaptureButton.Text = 'Wide Area Capture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lasso_gpt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end